classdef appProyect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FlitrosdeAudioPanel   matlab.ui.container.Panel
        Fc1EditFieldLabel     matlab.ui.control.Label
        Fc1                   matlab.ui.control.EditField
        Fc2EditFieldLabel     matlab.ui.control.Label
        Fc2                   matlab.ui.control.EditField
        FiltersDropDownLabel  matlab.ui.control.Label
        Filters               matlab.ui.control.DropDown
        tiempoE               matlab.ui.control.UIAxes
        espectroE             matlab.ui.control.UIAxes
        tiempoF               matlab.ui.control.UIAxes
        espectroF             matlab.ui.control.UIAxes
        AudioButton           matlab.ui.control.Button
        Graph                 matlab.ui.control.Button
        PO                    matlab.ui.control.Button
        PF                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        filename
        pathname
        a
        fs
        filter
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Filters
        function FiltersValueChanged(app, event)
            value = app.Filters.Value;
        end

        % Button pushed function: AudioButton
        function AudioButtonPushed(app, event)
            [app.filename, app.pathname] = uigetfile({'*.mp3'},'File Selector');
            [app.a, app.fs] = audioread([app.pathname,app.filename]);
        end

        % Button pushed function: Graph
        function GraphButtonPushed(app, event)
            
            
            corte1 = str2double(app.Fc1.Value);
            corte2 = str2double(app.Fc2.Value);
            
            %duracion del audio
            d = length(app.a)/app.fs;
            
            %audio monofonico
            a_m = 0.5*(app.a(:,1)+app.a(:,2)).';
            
            %tiempo
            t = linspace(0, d, length(a_m));
            
            %espectro
            L = length(app.a);
            NFFT = 2^nextpow2 (L);
            Y1 = fft(app.a);
            f1 = app.fs/2*linspace(0,1,NFFT/2);
            
            %graficar audio ---------------------------------------
            %tiempo
            plot(app.tiempoE,t, a_m);
            
            %espectro
            plot(app.espectroE,f1,2*abs(Y1(1:NFFT/2)));
            
            switch app.Filters.Value
                case 'High Pass'
                    % calcular filtro pasa alto
                    hp=highpass(app.a,corte1,app.fs);
                    app.filter = hp;
                    
                    L = length(hp);
                    NFFT = 2^nextpow2 (L);
                    Y1 = fft(hp);
                    f1 = app.fs/2*linspace(0,1,NFFT/2);
                    
                    % graficar pasa alto
                    plot(app.tiempoF,hp,'r');
                    
                    plot(app.espectroF,f1,2*abs(Y1(1:NFFT/2)),'r');
                    
                case 'Low Pass'
                    % calcular filtro paso bajo
                    lp= lowpass(app.a,corte1,app.fs);
                    app.filter = lp;
                    
                    L = length(lp);
                    NFFT = 2^nextpow2 (L);
                    Y1 = fft(lp);
                    f1 = app.fs/2*linspace(0,1,NFFT/2);
                    
                    % graficar paso bajo
                    plot(app.tiempoF,lp,'r');
                    
                    plot(app.espectroF,f1,2*abs(Y1(1:NFFT/2)),'r');
                    
                case 'Band Pass'
                    % calcular filtro paso bajo
                    bp= bandpass(app.a,[corte1 corte2],app.fs);
                    app.filter = bp;
                    
                    L = length(bp);
                    NFFT = 2^nextpow2 (L);
                    Y1 = fft(bp);
                    f1 = app.fs/2*linspace(0,1,NFFT/2);
                    
                    % graficar paso bajo
                    plot(app.tiempoF,bp,'r');
                    
                    plot(app.espectroF,f1,2*abs(Y1(1:NFFT/2)),'r');
                    
                case 'Band Stop'
                    % calcular filtro paso bajo
                    bs= bandstop(app.a,[corte1 corte2],app.fs);
                    app.filter = bs;
                    
                    L = length(bs);
                    NFFT = 2^nextpow2 (L);
                    Y1 = fft(bs);
                    f1 = app.fs/2*linspace(0,1,NFFT/2);
                    
                    % graficar paso bajo
                    plot(app.tiempoF,bs,'r');
                    
                    plot(app.espectroF,f1,2*abs(Y1(1:NFFT/2)),'r');
                    
            end
            
        end

        % Value changed function: Fc1
        function Fc1ValueChanged(app, event)
            value = app.Fc1.Value;
            
        end

        % Button pushed function: PO
        function POButtonPushed(app, event)
            sound(app.a,app.fs);
        end

        % Button pushed function: PF
        function PFButtonPushed(app, event)
            sound(app.filter,app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FlitrosdeAudioPanel = uipanel(app.UIFigure);
            app.FlitrosdeAudioPanel.Title = 'Flitros de Audio';
            app.Fc1EditFieldLabel = uilabel(app.FlitrosdeAudioPanel);
            app.Fc1EditFieldLabel.Text = 'Fc 1';
            app.Fc1 = uieditfield(app.FlitrosdeAudioPanel, 'text');
            app.Fc1.ValueChangedFcn = createCallbackFcn(app, @Fc1ValueChanged, true);
            app.Fc2EditFieldLabel = uilabel(app.FlitrosdeAudioPanel);
            app.Fc2EditFieldLabel.Text = 'Fc 2';
            app.Fc2 = uieditfield(app.FlitrosdeAudioPanel, 'text');
            app.Fc2.Tag = 'fc2';
            app.FiltersDropDownLabel = uilabel(app.FlitrosdeAudioPanel);
            app.FiltersDropDownLabel.Text = 'Filters';
            app.Filters = uidropdown(app.FlitrosdeAudioPanel);
            app.Filters.Items = {'High Pass', 'Low Pass', 'Band Pass', 'Band Stop'};
            app.Filters.ValueChangedFcn = createCallbackFcn(app, @FiltersValueChanged, true);
            app.Filters.Value = 'High Pass';
            app.tiempoE = uiaxes(app.FlitrosdeAudioPanel);
            title(app.tiempoE, 'Time')
            xlabel(app.tiempoE, 'Frequency (Hz)')
            ylabel(app.tiempoE, 'Width')
            app.espectroE = uiaxes(app.FlitrosdeAudioPanel);
            title(app.espectroE, 'Spectrum')
            xlabel(app.espectroE, 'Frequency (Hz)')
            ylabel(app.espectroE, 'Width')
            app.tiempoF = uiaxes(app.FlitrosdeAudioPanel);
            title(app.tiempoF, 'Time with filter')
            xlabel(app.tiempoF, 'Frequency (Hz)')
            ylabel(app.tiempoF, 'Width')
            app.espectroF = uiaxes(app.FlitrosdeAudioPanel);
            title(app.espectroF, 'Filtering spectrum')
            xlabel(app.espectroF, 'Frequency (Hz)')
            ylabel(app.espectroF, 'Width')
            app.AudioButton = uibutton(app.FlitrosdeAudioPanel, 'push');
            app.AudioButton.ButtonPushedFcn = createCallbackFcn(app, @AudioButtonPushed, true);
            app.AudioButton.Text = 'Audio Upload';
            app.Graph = uibutton(app.FlitrosdeAudioPanel, 'push');
            app.Graph.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.Graph.Text = 'Graph';
            app.PO = uibutton(app.FlitrosdeAudioPanel, 'push');
            app.PO.ButtonPushedFcn = createCallbackFcn(app, @POButtonPushed, true);
            app.PO.Text = 'Play Original';
            app.PF = uibutton(app.FlitrosdeAudioPanel, 'push');
            app.PF.ButtonPushedFcn = createCallbackFcn(app, @PFButtonPushed, true);
            app.PF.Text = 'Play Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appProyect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end