classdef Lab3_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uibuttongroup1  matlab.ui.container.ButtonGroup
        uipanel1        matlab.ui.container.Panel
        axes1           matlab.ui.control.UIAxes
        axes3           matlab.ui.control.UIAxes
        axes2           matlab.ui.control.UIAxes
        axes4           matlab.ui.control.UIAxes
        axes9           matlab.ui.control.UIAxes
        axes10          matlab.ui.control.UIAxes
        bitsG           matlab.ui.control.EditField
        tiempo          matlab.ui.control.EditField
        graficas        matlab.ui.control.Button
        text2           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        uipanel2        matlab.ui.container.Panel
        axes5           matlab.ui.control.UIAxes
        axes7           matlab.ui.control.UIAxes
        axes6           matlab.ui.control.UIAxes
        axes8           matlab.ui.control.UIAxes
        axes11          matlab.ui.control.UIAxes
        axes12          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Lab3_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Lab3 (see VARARGIN)
            
            % Choose default command line output for Lab3
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: graficas
        function graficas_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to graficas (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            bitsG = get(handles.bitsG,'string');
            tiempo = str2double(get(handles.tiempo,'string'));
            
            h=[];
            for x = 1:length(bitsG)
                h(end+1)=str2double(bitsG(x));
            end
            
            n=1;
            l=length(h);
            h(l+1)=1;
            ami=-1;
            %NRZ-L--------------------------------------------------------------------------
            
            while n<=length(h)-1;
                t=n-1:0.0001:n;
                if h(n) == 1
                    if h(n+1)==1
                        y=(t>n);
                    else
                        y=(t==n);
                    end
                    axes(handles.axes1);
                    d=plot(t*tiempo,y,'B');grid on;
                    title('NRZ-L');
                    set(d,'LineWidth',1.0);
                    hold on;
                    axis([0 tiempo*length(h) -1.5 1.5]);
                else
                    if h(n+1)==1
                        y=(t<n)-0*(t==n);
                    else
                        y=(t<n)+1*(t==n);
                    end
                    axes(handles.axes1);
                    d=plot(t*tiempo,y,'B');grid on;
                    title('NRZ-L');
                    set(d,'LineWidth',1.0);
                    hold on;
                    axis([0 tiempo*length(h) -1.5 1.5]);
                end
                n=n+1;
                %pause;
            end
            informacion=[];
            for n=1:1:length(h)
                if h(n)==1;
                    se=ones(1,100);
                else h(n)==0;
                    se=zeros(1,100);
                end
                informacion=[informacion se];
            end
            
            % espectro
            % Compute spectra of both modulated signals.
            %transformada rapida de fourier
            zdouble = fft(informacion);
            zdouble = abs(zdouble(1:length(zdouble)/2));
            frqdouble = [0:length(zdouble)-1]*100/length(zdouble)/2/tiempo;
            
            axes(handles.axes3);
            %figure;
            zoom on;
            d=plot(frqdouble/tiempo,zdouble);
            title('NRZ-L Espectro');
            set(d,'LineWidth',1.0);
            
            %NRZ-L--------------------------------------------------------------------------FIN
            bitsG = get(handles.bitsG,'string');
            tiempo = str2double(get(handles.tiempo,'string'));
            
            h=[];
            for x = 1:length(bitsG)
                h(end+1)=str2double(bitsG(x));
            end
            
            l=length(h);
            h(l+1)=1;
            pulse = 1;
            current_level = -pulse;
            %NRZ-I--------------------------------------------------------------------------
            
            for bit = 1:length(h)
                    % set bit time
                    bt=bit-1:0.001:bit;
            
                    if h(bit) == 0
                        % binary 0 - keep previous level
                        y = (bt<bit) * current_level;
                    else
                        % binary 1 - change pulse level
                        current_level = -current_level;
                        y = (bt<bit) * current_level;
                    end
            
                    % assign last pulse point by inspecting the following bit
                    try
                        if h(bit+1) == 1
                            y(end) = -current_level;
                        else
                            y(end) = current_level;
                        end
                    catch e
                        % bitstream end, assume next bit is 1
                        y(end) = -current_level;
                    end
                    axes(handles.axes2);
                    d=plot(bt*tiempo, y,'B');grid on;
                    title('NRZ-I Espectro');
                    set(d,'LineWidth',1.0);
                    hold on;
                    axis([0 tiempo*length(h) -1.5 1.5]);
                end
            informacion=[];
            for n=1:1:length(h)
                if h(n)==1;
                    se=ones(1,100);
                else h(n)==0;
                    se=zeros(1,100);
                end
                informacion=[informacion se];
            end
            
            % espectro
            zdouble = fft(informacion);
            zdouble = abs(zdouble(1:length(zdouble)/2+1));
            frqdouble = [0:length(zdouble)-1]*100/length(zdouble)/2/tiempo;
            
            axes(handles.axes4);
            d=plot(frqdouble/tiempo,zdouble);
            title('NRZ-I Espectro');
            set(d,'LineWidth',1.0);
            
            
            %NRZ-I--------------------------------------------------------------------------FIN
            
            bitsG = get(handles.bitsG,'string');
            tiempo = str2double(get(handles.tiempo,'string'));
            
            h=[];
            for x = 1:length(bitsG)
                h(end+1)=str2double(bitsG(x));
            end
            n=1;
            l=length(h);
            h(l+1)=1;
            ami=-1;
            %BIPOLAR AMI--------------------------------------------------------------------
            while n<=length(h)-1;
                t=n-1:0.001:n;
                disp(t);
                if h(n) == 0
                    if h(n+1)==0
                        y=(t>n);
                    else
                        if ami==1
                            y=-(t==n);
                        else
                            y=(t==n);
                        end
                    end
                    axes(handles.axes9);
                    d=plot(t*tiempo,y,'B');;grid on;
                    title('Bipolar AMI');
                    set(d,'LineWidth',1.0);
                    hold on;
                    axis([0 tiempo*length(h) -1.5 1.5]);
                else
                    ami=ami*-1;
                    if h(n+1)==0
                        if ami==1
                            y=(t<n);
                        else
                            y=-(t<n);
                        end
                    else
                        if ami==1
                            y=(t<n)-(t==n);
                        else
                            y=-(t<n)+(t==n);
                        end
            
                    end
                    axes(handles.axes9);
                    d=plot(t*tiempo,y,'B');grid on;
                    title('Bipolar AMI');
                    hold on;
                    set(d,'LineWidth',1.0);
                    axis([0 tiempo*length(h) -1.5 1.5]);
            
                end
                n=n+1;
            
            end
            informacion=[];
            bandera=true;
            for n=1:1:length(h)
                if h(n)==1;
                    if bandera == true
                        se=ones(1,100);
                        bandera=false;
                    else
                        se=ones(1,100)*-1;
                        bandera=true;
                    end
                else h(n)==0;
                    se=zeros(1,100);
                end
                informacion=[informacion se];
            end
            
            % espectro
            % Compute spectra of both modulated signals.
            %transformada rapida de fourier
            zdouble = fft(informacion);
            zdouble = abs(zdouble(1:length(zdouble)/2+1));
            frqdouble = [0:length(zdouble)-1]*100/length(zdouble)/2/tiempo;
            
            axes(handles.axes10);
            d=plot(frqdouble/tiempo,zdouble);
            title('Bipolar Espectro');
            set(d,'LineWidth',1.0);
            
            %BIPOLAR AMI--------------------------------------------------------------------FIN
            
            bitsG = get(handles.bitsG,'string');
            tiempo = str2double(get(handles.tiempo,'string'));
            
            h=[];
            for x = 1:length(bitsG)
                h(end+1)=str2double(bitsG(x));
            end
            n=1;
            l=length(h);
            h(l+1)=1;
            ami=-1;
            %Pseudoternario--------------------------------------------------------------------
            while n<=length(h)-1;
                t=n-1:0.001:n;
                if h(n) == 1
                    if h(n+1)==1
                        y=(t>n);
                    else
                        if ami==1
                            y=-(t==n);
                        else
                            y=(t==n);
                        end
                    end
                    axes(handles.axes5);
                    d=plot(t*tiempo,y,'B');;grid on;
                    title('Pseudoternario');
                    set(d,'LineWidth',1.0);
                    hold on;
                    axis([0 tiempo*length(h) -1.5 1.5]);
                else
                    ami=ami*-1;
                    if h(n+1)==1
                        if ami==1
                            y=(t<n);
                        else
                            y=-(t<n);
                        end
                    else
                        if ami==1
                            y=(t<n)-(t==n);
                        else
                            y=-(t<n)+(t==n);
                        end
            
                    end
                    axes(handles.axes5);
                    d=plot(t*tiempo,y,'B');grid on;
                    title('Pseudoternario');
                    hold on;
                    set(d,'LineWidth',1.0);
                    axis([0 tiempo*length(h) -1.5 1.5]);
            
                end
                n=n+1;
                %pause;
            end
            informacion=[];
            bandera=true;
            for n=1:1:length(h)
                if h(n)==0;
                    if bandera == true
                        se=ones(1,100);
                        bandera = false;
                    else
                        y=ones(1,100)*-1;
                        bandera = true;
                    end
                else h(n)==1;
                    se=zeros(1,100);
                end
                informacion=[informacion se];
            end
            
            % espectro
            % Compute spectra of both modulated signals.
            %transformada rapida de fourier
            zdouble = fft(informacion);
            zdouble = abs(zdouble(1:length(zdouble)/2+1));
            frqdouble = [0:length(zdouble)-1]*100/length(zdouble)/2/tiempo;
            
            axes(handles.axes7);
            d=plot(frqdouble/tiempo,zdouble);
            title('Pseud. Espectro');
            set(d,'LineWidth',1.0);
            %Pseudoternario--------------------------------------------------------------------FIN
            
            bitsG = get(handles.bitsG,'string');
            tiempo = str2double(get(handles.tiempo,'string'));
            
            h=[];
            for x = 1:length(bitsG)
                h(end+1)=str2double(bitsG(x));
            end
            n=1;
            l=length(h);
            h(l+1)=1;
            %Manchester--------------------------------------------------------------------
            while n<=length(h)-1;
                t=n-1:0.001:n;
                if h(n) == 0
                    if h(n+1)==0
                        y=-(t<n)+2*(t<n-0.5)+1*(t==n);
                    else
                        y=-(t<n)+2*(t<n-0.5)-1*(t==n);
                    end
                    axes(handles.axes6);
                    d=plot(t*tiempo,y,'B');grid on;
                    title('MANCHESTER');
                    set(d,'LineWidth',1.0);
                    hold on;
                    axis([0 tiempo*length(h) -1.5 1.5]);
                else
                    if h(n+1)==0
                        y=(t<n)-2*(t<n-0.5)+1*(t==n);
                    else
                        y=(t<n)-2*(t<n-0.5)-1*(t==n);
                    end
                    axes(handles.axes6);
                    d=plot(t*tiempo,y,'B');grid on;
                    title('MANCHESTER');
                    set(d,'LineWidth',1.0);
                    hold on;
                    axis([0 tiempo*length(h) -1.5 1.5]);
                end
                n=n+1;
                %pause;
            end
            % espectro
            informacion=[];
            for n=1:1:length(h)
                if h(n)==0;
                    se=ones(1,50);
                    b = ones(1,50)*-1;
                    y=[se b];
                else h(n)==1;
                    se=ones(1,50);
                    b = ones(1,50)*-1;
                    y=[b se];
                end
                informacion=[informacion y];
            end
            
            zdouble = fft(informacion);
            zdouble = abs(zdouble(1:length(zdouble)/2+1));
            frqdouble = [0:length(zdouble)-1]*100/length(zdouble)/2/tiempo;
            
            axes(handles.axes8);
            d=plot(frqdouble/tiempo,zdouble);
            title('Manch. Espectro');
            set(d,'LineWidth',1.0);
            %Manchester--------------------------------------------------------------------FIN
            
            bitsG = get(handles.bitsG,'string');
            tiempo = str2double(get(handles.tiempo,'string'));
            
            h=[];
            for x = 1:length(bitsG)
                h(end+1)=str2double(bitsG(x));
            end
            l=length(h);
            h(l+1)=1;
            
            pulse = 1;
            
            current_level = +pulse;
            %Manchester Diferencial---------------------------------------------------------------------
            
            for bit = 1:length(h)
                bt = bit-1:0.001:bit;
                if current_level < 0
                    y = (bt<bit) * pulse -2 * pulse * (bt < bit - 0.5);
                else
                    y = -(bt<bit) * pulse + 2 * pulse * (bt < bit - 0.5);
                end
            
                current_level = -current_level;
            
                try
                    if h(bit+1) == 0
            
                        current_level = -current_level;
                    end
                catch e
            
                end
            
                y(end) = current_level;
            
                axes(handles.axes11);
                d=plot(bt*tiempo,y,'B');grid on;
                title('MANCHESTER DIF.');
                set(d,'LineWidth',1.0);
                hold on;
                axis([0 tiempo*length(h) -1.5 1.5]);
            
            end
            
            informacion=[];
            for n=1:1:length(h)
                if h(n)==0;
                    se=ones(1,50);
                    b = ones(1,50)*-1;
                    y=[se b];
                else h(n)==1;
                    se=ones(1,50);
                    b = ones(1,50)*-1;
                    y=[b se];
                end
                informacion=[informacion y];
            end
            
            zdouble = fft(informacion);
            zdouble = abs(zdouble(1:length(zdouble)/2+1));
            frqdouble = [0:length(zdouble)-1]*100/length(zdouble)/2/tiempo;
            
            axes(handles.axes12);
            d=plot(frqdouble/tiempo,zdouble);
            title('ManchDif. Espectro');
            set(d,'LineWidth',1.0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Lab3';
            app.figure1.Tag = 'figure1';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Graficas Linea';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.uipanel1 = uipanel(app.uibuttongroup1);
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.axes3 = uiaxes(app.uipanel1);
            app.axes3.Tag = 'axes3';
            app.axes2 = uiaxes(app.uipanel1);
            app.axes2.Tag = 'axes2';
            app.axes4 = uiaxes(app.uipanel1);
            app.axes4.Tag = 'axes4';
            app.axes9 = uiaxes(app.uipanel1);
            app.axes9.Tag = 'axes9';
            app.axes10 = uiaxes(app.uipanel1);
            app.axes10.Tag = 'axes10';
            app.bitsG = uieditfield(app.uibuttongroup1, 'text');
            app.bitsG.Tag = 'bitsG';
            app.tiempo = uieditfield(app.uibuttongroup1, 'text');
            app.tiempo.Tag = 'tiempo';
            app.graficas = uibutton(app.uibuttongroup1, 'push');
            app.graficas.ButtonPushedFcn = createCallbackFcn(app, @graficas_Callback, true);
            app.graficas.Tag = 'graficas';
            app.graficas.Text = 'Graficar';
            app.text2 = uilabel(app.uibuttongroup1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Bits';
            app.text3 = uilabel(app.uibuttongroup1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Tiempo';
            app.uipanel2 = uipanel(app.uibuttongroup1);
            app.uipanel2.Tag = 'uipanel2';
            app.axes5 = uiaxes(app.uipanel2);
            app.axes5.Tag = 'axes5';
            app.axes7 = uiaxes(app.uipanel2);
            app.axes7.Tag = 'axes7';
            app.axes6 = uiaxes(app.uipanel2);
            app.axes6.Tag = 'axes6';
            app.axes8 = uiaxes(app.uipanel2);
            app.axes8.Tag = 'axes8';
            app.axes11 = uiaxes(app.uipanel2);
            app.axes11.Tag = 'axes11';
            app.axes12 = uiaxes(app.uipanel2);
            app.axes12.Tag = 'axes12';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab3_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Lab3_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end