classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        INPUTPanel            matlab.ui.container.Panel
        Graficar              matlab.ui.control.Button
        BitsEditFieldLabel    matlab.ui.control.Label
        Bits                  matlab.ui.control.EditField
        TiempoEditFieldLabel  matlab.ui.control.Label
        Tiempo                matlab.ui.control.EditField
        AnguloEditFieldLabel  matlab.ui.control.Label
        Angulo                matlab.ui.control.EditField
        RightPanel            matlab.ui.container.Panel
        GraficasPanel         matlab.ui.container.Panel
        GrafTiempo            matlab.ui.control.UIAxes
        GrafEspectro          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Graficar
        function GraficarPushed(app, event)
            tiempo = str2double(app.Tiempo.Value);
            angulo = str2double(app.Angulo.Value);
            bitsig = num2str(app.Bits.Value)-'0';
            
            angulo = angulo-112.5;
            
            A = sqrt(2);
            
            Fs = 100;
            
            t = tiempo/99:tiempo/99:tiempo;
            
            bits = [];
            
            bits = [bits bitsig];
            
            disp(angulo)
            angulo1 = angulo + -157.5;
            disp(angulo1)
            angulo2 = angulo1 + -67.5;
            disp(angulo2)
            angulo3 = angulo2 + -22.5;
            disp(angulo3)
            angulo4 = angulo3 + 112.5;
            disp(angulo4)
            angulo5 = angulo4 + 157.5;
            disp(angulo5)
            angulo6 = angulo5 + 67.5;
            disp(angulo6)
            angulo7 = angulo6 + 22.5;
            disp(angulo7)
            
            x0 = A*cos(2*pi*Fs*t+deg2rad(angulo7));%000
            x4 = A*cos(2*pi*Fs*t+deg2rad(angulo3));%001
            x1 = A*cos(2*pi*Fs*t+deg2rad(angulo6));%010
            x5 = A*cos(2*pi*Fs*t+deg2rad(angulo2));%011
            x2 = A*cos(2*pi*Fs*t+deg2rad(angulo5));%100
            x6 = A*cos(2*pi*Fs*t+deg2rad(angulo1));%101
            x3 = A*cos(2*pi*Fs*t+deg2rad(angulo4));%110
            x7 = A*cos(2*pi*Fs*t+deg2rad(angulo));%111
            senalmod = [];
            
            for i=1:3:(length(bits)-1)
                if (bits(i)==0 && bits(i+1)==0 && bits(i+1)==0)
                    senalmod = [senalmod x0];
                elseif (bits(i)==0 && bits(i+1)==1 && bits(i+1)==0)
                    senalmod = [senalmod x1];
                elseif (bits(i)==1 && bits(i+1)==0 && bits(i+1)==0)
                    senalmod = [senalmod x2];
                elseif (bits(i)==1 && bits(i+1)==1 && bits(i+1)==0)
                    senalmod = [senalmod x3];
                elseif (bits(i)==0 && bits(i+1)==0 && bits(i+1)==1)
                    senalmod = [senalmod x4];
                elseif (bits(i)==0 && bits(i+1)==1 && bits(i+1)==1)
                    senalmod = [senalmod x5];
                elseif (bits(i)==1 && bits(i+1)==0 && bits(i+1)==1)
                    senalmod = [senalmod x6];
                elseif (bits(i)==1 && bits(i+1)==1 && bits(i+1)==1)
                    senalmod = [senalmod x7];
                end
            end
            
            tt = tiempo/99:tiempo/99:(tiempo*length(bits))/3;
            
            plot(app.GrafTiempo,tt,senalmod);
            
            zdouble= fft(senalmod);
            zdouble = abs(zdouble(floor(1:length(zdouble)/2+1)));
            frqdouble = [0:length(zdouble)-1]'*Fs/length(zdouble)/2;
            
            plot(app.GrafEspectro,frqdouble,zdouble);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {457, 457};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.INPUTPanel = uipanel(app.LeftPanel);
            app.INPUTPanel.Title = 'INPUT';
            app.Graficar = uibutton(app.INPUTPanel, 'push');
            app.Graficar.ButtonPushedFcn = createCallbackFcn(app, @GraficarPushed, true);
            app.Graficar.Text = 'Graficar';
            app.BitsEditFieldLabel = uilabel(app.INPUTPanel);
            app.BitsEditFieldLabel.Text = 'Bits';
            app.Bits = uieditfield(app.INPUTPanel, 'text');
            app.TiempoEditFieldLabel = uilabel(app.INPUTPanel);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.Tiempo = uieditfield(app.INPUTPanel, 'text');
            app.AnguloEditFieldLabel = uilabel(app.INPUTPanel);
            app.AnguloEditFieldLabel.Text = 'Angulo';
            app.Angulo = uieditfield(app.INPUTPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.GraficasPanel = uipanel(app.RightPanel);
            app.GraficasPanel.Title = 'Graficas';
            app.GrafTiempo = uiaxes(app.GraficasPanel);
            title(app.GrafTiempo, 'Tiempo')
            xlabel(app.GrafTiempo, 'X')
            ylabel(app.GrafTiempo, 'Y')
            app.GrafEspectro = uiaxes(app.GraficasPanel);
            title(app.GrafEspectro, 'Espectro')
            xlabel(app.GrafEspectro, 'X')
            ylabel(app.GrafEspectro, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end